classdef ECG_TEM_GUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        UITable                     matlab.ui.control.Table
        RunningLamp                 matlab.ui.control.Lamp
        RunningLampLabel            matlab.ui.control.Label
        Image_4                     matlab.ui.control.Image
        SamplingRatesKHzPanel       matlab.ui.container.Panel
        ProposredApproachEditField  matlab.ui.control.NumericEditField
        ProposredApproachLabel      matlab.ui.control.Label
        NyquistEditField            matlab.ui.control.NumericEditField
        NyquistEditFieldLabel       matlab.ui.control.Label
        Image_3                     matlab.ui.control.Image
        SCENARIOSETTINGSPanel       matlab.ui.container.Panel
        SelectpersonButtonGroup     matlab.ui.container.ButtonGroup
        Person3Button               matlab.ui.control.RadioButton
        Person2Button               matlab.ui.control.RadioButton
        Person1Button               matlab.ui.control.RadioButton
        StartButton                 matlab.ui.control.Button
        HardwareSwitch              matlab.ui.control.Switch
        HardwareSwitchLabel         matlab.ui.control.Label
        ModeSwitch                  matlab.ui.control.Switch
        ModeSwitchLabel             matlab.ui.control.Label
        HeartRateMonitoringforECGsignalswithTimeEncodingMachineLabel  matlab.ui.control.Label
        Image_2                     matlab.ui.control.Image
        Image                       matlab.ui.control.Image
        UIAxes_7                    matlab.ui.control.UIAxes
        UIAxes_6                    matlab.ui.control.UIAxes
        UIAxes_5                    matlab.ui.control.UIAxes
        UIAxes_4                    matlab.ui.control.UIAxes
        UIAxes_2                    matlab.ui.control.UIAxes
        UIAxes                      matlab.ui.control.UIAxes
        ContextMenu                 matlab.ui.container.ContextMenu
        Menu                        matlab.ui.container.Menu
        Menu2                       matlab.ui.container.Menu
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            global Val
            Val = 'Simulation';
            app.RunningLamp.Color = 'r';
            % Add data to the table UI Component
            app.UITable.Data = {'ECG-TEM';'VPW-FRI';'Diff. VPW'};

        end

        % Button pushed function: StartButton
        function StartButtonPushed(app, event)
            global Val
            if strcmp(app.HardwareSwitch.Value,'Off')
                if strcmp(Val,'Simulation')
                    cla(app.UIAxes);
                    cla(app.UIAxes_2);
                    cla(app.UIAxes_4);
                    cla(app.UIAxes_7);
                    cla(app.UIAxes_5);
                    cla(app.UIAxes_6);
                    if app.Person1Button.Value
                        openfig('./figs_for_demo_sim/GDN001_PUREfig.fig','invisible');
                        a = get(gca,'Children');
                        xdata = get(a, 'XData');
                        ydata = get(a, 'YData');
                        plot(app.UIAxes,xdata,ydata);
                        openfig('./figs_for_demo_sim/ecg_sampling.fig','invisible');
                        a = findobj(gca,'Type','line');
                        x1 = get(a(1), 'XData');
                        y1 = get(a(1), 'YData');
                        x2 = get(a(2),'XData');
                        y2 = get(a(2),'Ydata');
                        x3 = get(a(3),'XData');
                        y3 = get(a(3),'Ydata');
                        [t,locs] = findpeaks(y2);
                        z = zeros(1,length(y2));
                        z(locs) = y2(locs);
                        hold(app.UIAxes_7,'on')
                        plot(app.UIAxes_7,x2,y2,'color',[0,0.5,0])
                        plot(app.UIAxes_7,x1,y1,'LineWidth',4,'Color','black')
                        plot(app.UIAxes_7,x3,y3,'LineWidth',4,'Color','b')
                        stem(app.UIAxes_7,x3,z,'LineStyle','none','Color','r')
                        hold(app.UIAxes_7,'off')
                        openfig('./figs_for_demo_sim/ecg_reconstruction.fig','invisible');
                        a = findobj(gca,'Type','line');
                        x1 = get(a(1), 'XData');
                        y1 = get(a(1), 'YData');
                        x2 = get(a(2),'XData');
                        y2 = get(a(2),'Ydata');
                        x3 = get(a(3),'XData');
                        y3 = get(a(3),'Ydata');
                        x4 = get(a(4),'XData');
                        y4 = get(a(4),'Ydata');
                        hold(app.UIAxes_2,'on')
                        plot(app.UIAxes_2,x2,y2,'--','LineWidth',2,'color',[0,0,0])
                        plot(app.UIAxes_2,x1,y1,'LineWidth',2,'Color',[0,1,0])
                        plot(app.UIAxes_2,x3,y3,'--','LineWidth',2,'Color','b')
                        plot(app.UIAxes_2,x4,y4,'--','LineWidth',2,'Color','r')
                        hold(app.UIAxes_2,'off')
                        close all
                        openfig('./figs_for_demo_sim/diff_GDN001_HRM.fig','invisible');
                        a = findobj(gca,'Type','line');
                        x1 = get(a(1), 'XData');
                        y1 = get(a(1), 'YData');
                        x2 = get(a(2),'XData');
                        y2 = get(a(2),'Ydata');

                        hold(app.UIAxes_5,'on')
                        plot(app.UIAxes_5,x2,y2,'LineWidth',2,'color',[0,0,0])
                        plot(app.UIAxes_5,x1,y1,'LineWidth',2,'Color',[0,0,1])
                        hold(app.UIAxes_6,'off')
                        close all
                        openfig('./figs_for_demo_sim/IF-TEM_GDN001_HRM.fig','invisible');
                        a = findobj(gca,'Type','line');
                        x1 = get(a(1), 'XData');
                        y1 = get(a(1), 'YData');
                        x2 = get(a(2),'XData');
                        y2 = get(a(2),'Ydata');

                        hold(app.UIAxes_6,'on')
                        plot(app.UIAxes_6,x2,y2,'LineWidth',2,'color',[0,0,0])
                        plot(app.UIAxes_6,x1,y1,'LineWidth',2,'Color',[0.8,0,0])
                        hold(app.UIAxes_6,'off')
                        close all
                        openfig('./figs_for_demo_sim/VPW_GDN001_HRM.fig','invisible');
                        a = findobj(gca,'Type','line');
                        x1 = get(a(1), 'XData');
                        y1 = get(a(1), 'YData');
                        x2 = get(a(2),'XData');
                        y2 = get(a(2),'Ydata');
                        hold(app.UIAxes_4,'on')
                        plot(app.UIAxes_4,x2,y2,'LineWidth',2,'color',[0,0,0])
                        plot(app.UIAxes_4,x1,y1,'LineWidth',2,'Color',[0,0.8,0])
                        hold(app.UIAxes_4,'off')
                        TEM_HRM_PREP(1);
                        final_measurements_TEM = run_HRM();
                        VPW_PREP(1);
                        final_measurements_VPW = run_HRM();
                        Differential_PREP(1);
                        final_measurements_diff = run_HRM();
                        app.UITable.Data = {'ECG-TEM' final_measurements_TEM(1) final_measurements_TEM(3)/100 final_measurements_TEM(4) final_measurements_TEM(5);
                            'VPW-FRI' final_measurements_VPW(1) final_measurements_VPW(3)/100 final_measurements_VPW(4) final_measurements_VPW(5);
                            'Diff. VPW' final_measurements_diff(1) final_measurements_diff(3)/100 final_measurements_diff(4) final_measurements_diff(5)};
                    end


                    if app.Person2Button.Value
                        openfig('./figs_for_demo_sim/GDN0011_PURE.fig','invisible');
                        a = get(gca,'Children');
                        xdata = get(a, 'XData');
                        ydata = get(a, 'YData');
                        plot(app.UIAxes,xdata,ydata);
                        openfig('./figs_for_demo_sim/GDN011_SAMPLING.fig','invisible');
                        a = findobj(gca,'Type','line');
                        x1 = get(a(1), 'XData');
                        y1 = get(a(1), 'YData');
                        x2 = get(a(2),'XData');
                        y2 = get(a(2),'Ydata');
                        x3 = get(a(3),'XData');
                        y3 = get(a(3),'Ydata');
                        [t,locs] = findpeaks(y2);
                        z = zeros(1,length(y2));
                        z(locs) = y2(locs);
                        hold(app.UIAxes_7,'on')
                        plot(app.UIAxes_7,x2,y2,'color',[0,0.5,0])
                        plot(app.UIAxes_7,x1,y1,'LineWidth',4,'Color','black')
                        plot(app.UIAxes_7,x3,y3,'LineWidth',4,'Color','b')
                        stem(app.UIAxes_7,x3,z,'LineStyle','none','Color','r')
                        hold(app.UIAxes_7,'off')
                        openfig('./figs_for_demo_sim/GDN0011_SIMULATION.fig','invisible');
                        a = findobj(gca,'Type','line');
                        x1 = get(a(1), 'XData');
                        y1 = get(a(1), 'YData');
                        x2 = get(a(2),'XData');
                        y2 = get(a(2),'Ydata');
                        x3 = get(a(3),'XData');
                        y3 = get(a(3),'Ydata');
                        x4 = get(a(4),'XData');
                        y4 = get(a(4),'Ydata');
                        hold(app.UIAxes_2,'on')
                        plot(app.UIAxes_2,x2,y2,'--','LineWidth',2,'color',[0,0,0])
                        plot(app.UIAxes_2,x1,y1,'LineWidth',2,'Color',[0,1,0])
                        plot(app.UIAxes_2,x3,y3,'--','LineWidth',2,'Color','b')
                        plot(app.UIAxes_2,x4,y4,'--','LineWidth',2,'Color','r')
                        hold(app.UIAxes_2,'off')
                        close all
                        openfig('./figs_for_demo_sim/IF-TEM_GDN0011_HRM.fig','invisible');
                        a = findobj(gca,'Type','line');
                        x1 = get(a(1), 'XData');
                        y1 = get(a(1), 'YData');
                        x2 = get(a(2),'XData');
                        y2 = get(a(2),'Ydata');

                        hold(app.UIAxes_6,'on')
                        plot(app.UIAxes_6,x2,y2,'LineWidth',2,'color',[0,0,0])
                        plot(app.UIAxes_6,x1,y1,'LineWidth',2,'Color',[0.8,0,0])
                        close all
                        openfig('./figs_for_demo_sim/VPW_GDN0011_HRM.fig','invisible');
                        a = findobj(gca,'Type','line');
                        x1 = get(a(1), 'XData');
                        y1 = get(a(1), 'YData');
                        x2 = get(a(2),'XData');
                        y2 = get(a(2),'Ydata');

                        hold(app.UIAxes_4,'on')
                        plot(app.UIAxes_4,x2,y2,'LineWidth',2,'color',[0,0,0])
                        plot(app.UIAxes_4,x1,y1,'LineWidth',2,'Color',[0,0.8,0])
                        close all
                        openfig('./figs_for_demo_sim/diff_GDN0011_HRM.fig','invisible');
                        a = findobj(gca,'Type','line');
                        x1 = get(a(1), 'XData');
                        y1 = get(a(1), 'YData');
                        x2 = get(a(2),'XData');
                        y2 = get(a(2),'Ydata');

                        hold(app.UIAxes_5,'on')
                        plot(app.UIAxes_5,x2,y2,'LineWidth',2,'color',[0,0,0])
                        plot(app.UIAxes_5,x1,y1,'LineWidth',2,'Color',[0,0,1])
                        hold(app.UIAxes_5,'off')
                        TEM_HRM_PREP(2);
                        final_measurements_TEM = run_HRM();
                        VPW_PREP(2);
                        final_measurements_VPW = run_HRM();
                        Differential_PREP(2);
                        final_measurements_diff = run_HRM();
                        app.UITable.Data = {'ECG-TEM' final_measurements_TEM(1) final_measurements_TEM(3)/100 final_measurements_TEM(4) final_measurements_TEM(5);
                            'VPW-FRI' final_measurements_VPW(1) final_measurements_VPW(3)/100 final_measurements_VPW(4) final_measurements_VPW(5);
                            'Diff. VPW' final_measurements_diff(1) final_measurements_diff(3)/100 final_measurements_diff(4) final_measurements_diff(5)};
                    end
                    close all


                    if app.Person3Button.Value
                        openfig('./figs_for_demo_sim/GDN0020_PURE.fig','invisible');
                        a = get(gca,'Children');
                        xdata = get(a, 'XData');
                        ydata = get(a, 'YData');
                        plot(app.UIAxes,xdata,ydata);
                        openfig('./figs_for_demo_sim/GDN0020_Smapling.fig','invisible');
                        a = findobj(gca,'Type','line');
                        x1 = get(a(1), 'XData');
                        y1 = get(a(1), 'YData');
                        x2 = get(a(2),'XData');
                        y2 = get(a(2),'Ydata');
                        x3 = get(a(3),'XData');
                        y3 = get(a(3),'Ydata');
                        [t,locs] = findpeaks(y2);
                        z = zeros(1,length(y2));
                        z(locs) = y2(locs);
                        hold(app.UIAxes_7,'on')
                        plot(app.UIAxes_7,x2,y2,'color',[0,0.5,0])
                        plot(app.UIAxes_7,x1,y1,'LineWidth',4,'Color','black')
                        plot(app.UIAxes_7,x3,y3,'LineWidth',4,'Color','b')
                        stem(app.UIAxes_7,x3,z,'LineStyle','none','Color','r')
                        hold(app.UIAxes_7,'off')
                        openfig('./figs_for_demo_sim/GDN0020_SIMULATION.fig','invisible');
                        a = findobj(gca,'Type','line');
                        x1 = get(a(1), 'XData');
                        y1 = get(a(1), 'YData');
                        x2 = get(a(2),'XData');
                        y2 = get(a(2),'Ydata');
                        x3 = get(a(3),'XData');
                        y3 = get(a(3),'Ydata');
                        x4 = get(a(4),'XData');
                        y4 = get(a(4),'Ydata');
                        hold(app.UIAxes_2,'on')
                        plot(app.UIAxes_2,x2,y2,'--','LineWidth',2,'color',[0,0,0])
                        plot(app.UIAxes_2,x1,y1,'LineWidth',2,'Color',[0,1,0])
                        plot(app.UIAxes_2,x3,y3,'--','LineWidth',2,'Color','b')
                        plot(app.UIAxes_2,x4,y4,'--','LineWidth',2,'Color','r')
                        hold(app.UIAxes_2,'off')
                        close all
                        openfig('./figs_for_demo_sim/IF-TEM_GDN0020_HRM.fig','invisible');
                        a = findobj(gca,'Type','line');
                        x1 = get(a(1), 'XData');
                        y1 = get(a(1), 'YData');
                        x2 = get(a(2),'XData');
                        y2 = get(a(2),'Ydata');

                        hold(app.UIAxes_6,'on')
                        plot(app.UIAxes_6,x2,y2,'LineWidth',2,'color',[0,0,0])
                        plot(app.UIAxes_6,x1,y1,'LineWidth',2,'Color',[0.8,0,0])
                        close all
                        openfig('./figs_for_demo_sim/VPW_GDN0020_HRM.fig','invisible');
                        a = findobj(gca,'Type','line');
                        x1 = get(a(1), 'XData');
                        y1 = get(a(1), 'YData');
                        x2 = get(a(2),'XData');
                        y2 = get(a(2),'Ydata');

                        hold(app.UIAxes_4,'on')
                        plot(app.UIAxes_4,x2,y2,'LineWidth',2,'color',[0,0,0])
                        plot(app.UIAxes_4,x1,y1,'LineWidth',2,'Color',[0,0.8,0])
                        close all
                        openfig('./figs_for_demo_sim/diff_GDN0020_HRM.fig','invisible');
                        a = findobj(gca,'Type','line');
                        x1 = get(a(1), 'XData');
                        y1 = get(a(1), 'YData');
                        x2 = get(a(2),'XData');
                        y2 = get(a(2),'Ydata');

                        hold(app.UIAxes_5,'on')
                        plot(app.UIAxes_5,x2,y2,'LineWidth',2,'color',[0,0,0])
                        plot(app.UIAxes_5,x1,y1,'LineWidth',2,'Color',[0,0,1])
                        close all
                    end

                    if strcmp(Val,'Hybrid')
                        cla(app.UIAxes);
                        cla(app.UIAxes_2);
                        cla(app.UIAxes_4);
                        cla(app.UIAxes_7);
                        cla(app.UIAxes_5);
                        cla(app.UIAxes_6);
                        if app.Person1Button.Value
                            openfig('./figs_for_demo_sim/GDN001_PUREfig.fig','invisible');
                            a = get(gca,'Children');
                            xdata = get(a, 'XData');
                            ydata = get(a, 'YData');
                            plot(app.UIAxes,xdata,ydata);
                            openfig('./figs_for_demo_sim/ecg_sampling.fig','invisible');
                            a = findobj(gca,'Type','line');
                            x1 = get(a(1), 'XData');
                            y1 = get(a(1), 'YData');
                            x2 = get(a(2),'XData');
                            y2 = get(a(2),'Ydata');
                            x3 = get(a(3),'XData');
                            y3 = get(a(3),'Ydata');
                            [t,locs] = findpeaks(y2);
                            z = zeros(1,length(y2));
                            z(locs) = y2(locs);
                            hold(app.UIAxes_7,'on')
                            plot(app.UIAxes_7,x2,y2,'color',[0,0.5,0])
                            plot(app.UIAxes_7,x1,y1,'LineWidth',4,'Color','black')
                            plot(app.UIAxes_7,x3,y3,'LineWidth',4,'Color','b')
                            stem(app.UIAxes_7,x3,z,'LineStyle','none','Color','r')
                            hold(app.UIAxes_7,'off')
                            app.RunningLamp.Color = [0,0,1];
                            pause(1);
                            M = load('TEM_PYTHON\DSOX1204G_TEM_pat1.mat');
                            %M = M(:,2)';
                            og = M.y1;
                            M = M.y3';
                            [x,tau] = findpeaks(M,'MinPeakDistance',40,'MinPeakHeight',1);
                            M = tau/length(M);
                            tn = M;
                            b=1.75;
                            d=1.133;
                            kappa=0.014;
                            b=0.78;d=0.99;kappa=0.018;
                            dt = 1/50;
                            yDel = -b*diff(tn) + kappa*d;
                            K =5;
                            T = 1;
                            N =50;
                            K = 4*K+2;
                            w0 = 2*pi/T;
                            F = exp(1j*w0*tn(2:end)'*(-K:K)) - exp(1j*w0*tn(1:end-1)'*(-K:K));
                            F(:,K+1) = tn(2:end) - tn(1:end-1);
                            s = T./(1j*2*pi*(-K:K)); s(K+1) = 1;
                            S = diag(s);
                            ytnHat = pinv(F*S)*yDel';
                            ytnHat = ytnHat'*N;
                            spectrum = transpose(conj(ytnHat(K+1:end)));
                            spectrum = cadzow(spectrum,inf,K);
                            save('TEM_PYTHON\spectrum.mat','spectrum');
                            disp("saved!");
                            xyo = system('python3 TEM_PYTHON\temp1.py');
                            outputsignal = readmatrix('pulse11_100_hw_recon.csv');
                            hold(app.UIAxes_2,'on')
                            plot(app.UIAxes_2,(outputsignal(:,2)-0.1)/max(outputsignal(:,2)),'--','LineWidth',2,'color',[0,1,0])
                            plot(app.UIAxes_2,(resample(og,200,length(og))-0.2)./max(og),'LineWidth',2,'Color',[0,0,0])
                            hold(app.UIAxes_2,'off')
                            %plot((outputsignal(:,2)-0.1)/max(outputsignal(:,2)));hold on;plot((resample(og,200,length(og))-0.2)./max(og));
                            app.RunningLamp.Color = 'r';
                        end
                        if app.Person2Button.Value
                            openfig('./figs_for_demo_sim/GDN0011_PURE.fig','invisible');
                            a = get(gca,'Children');
                            xdata = get(a, 'XData');
                            ydata = get(a, 'YData');
                            plot(app.UIAxes,xdata,ydata);
                            openfig('./figs_for_demo_sim/GDN011_SAMPLING.fig','invisible');
                            a = findobj(gca,'Type','line');
                            x1 = get(a(1), 'XData');
                            y1 = get(a(1), 'YData');
                            x2 = get(a(2),'XData');
                            y2 = get(a(2),'Ydata');
                            x3 = get(a(3),'XData');
                            y3 = get(a(3),'Ydata');
                            [t,locs] = findpeaks(y2);
                            z = zeros(1,length(y2));
                            z(locs) = y2(locs);
                            hold(app.UIAxes_7,'on')
                            plot(app.UIAxes_7,x2,y2,'color',[0,0.5,0])
                            plot(app.UIAxes_7,x1,y1,'LineWidth',4,'Color','black')
                            plot(app.UIAxes_7,x3,y3,'LineWidth',4,'Color','b')
                            stem(app.UIAxes_7,x3,z,'LineStyle','none','Color','r')
                            hold(app.UIAxes_7,'off')
                            app.RunningLamp.Color = [0,0,1];
                            pause(1);
                            M = load('TEM_PYTHON/DSOX1204G_TEM_pat2.mat');
                            og = M.y1;
                            M = M.y3';
                            [x,tau] = findpeaks(M,'MinPeakDistance',40,'MinPeakHeight',1);
                            M = tau/length(M);
                            tn = M;
                            b=1.75;
                            d=1.133;
                            kappa=0.014;
                            b=0.78;d=0.99;kappa=0.018;
                            dt = 1/50;
                            yDel = -b*diff(tn) + kappa*d;
                            K =7;
                            T = 1;
                            N =50;
                            K = 4*K+2;
                            w0 = 2*pi/T;
                            F = exp(1j*w0*tn(2:end)'*(-K:K)) - exp(1j*w0*tn(1:end-1)'*(-K:K));
                            F(:,K+1) = tn(2:end) - tn(1:end-1);
                            s = T./(1j*2*pi*(-K:K)); s(K+1) = 1;
                            S = diag(s);

                            ytnHat = pinv(F*S)*yDel';
                            ytnHat = ytnHat'*N;
                            spectrum = transpose(conj(ytnHat(K+1:end)));
                            spectrum = cadzow(spectrum,inf,K);
                            save('TEM_PYTHON\spectrum.mat','spectrum');
                            disp("saved!");

                            xyo = system('python3 TEM_PYTHON/temp2.py');
                            outputsignal = readmatrix('TEM_PYTHON/pulse2.csv');
                            outputsignal(1:20,:) = -outputsignal(1:20,:);
                            hold(app.UIAxes_2,'on')
                            plot(app.UIAxes_2,(outputsignal(:,2)-0.1)/max(outputsignal(:,2)),'--','LineWidth',2,'color',[1,0,0])
                            plot(app.UIAxes_2,(resample(og,50,length(og))-0.2)./max(og),'LineWidth',2,'Color',[0,0,0])
                            hold(app.UIAxes_2,'off')
                            %plot((outputsignal(:,2)-0.1)/max(outputsignal(:,2)));hold on;plot((resample(og,200,length(og))-0.2)./max(og));
                            app.RunningLamp.Color = 'r';
                        end
                        if app.Person3Button.Value
                            openfig('./figs_for_demo_sim/GDN0020_PURE.fig','invisible');
                            a = get(gca,'Children');
                            xdata = get(a, 'XData');
                            ydata = get(a, 'YData');
                            plot(app.UIAxes,xdata,ydata);
                            openfig('./figs_for_demo_sim/GDN0020_Smapling.fig','invisible');
                            a = findobj(gca,'Type','line');
                            x1 = get(a(1), 'XData');
                            y1 = get(a(1), 'YData');
                            x2 = get(a(2),'XData');
                            y2 = get(a(2),'Ydata');
                            x3 = get(a(3),'XData');
                            y3 = get(a(3),'Ydata');
                            [t,locs] = findpeaks(y2);
                            z = zeros(1,length(y2));
                            z(locs) = y2(locs);
                            hold(app.UIAxes_7,'on')
                            plot(app.UIAxes_7,x2,y2,'color',[0,0.5,0])
                            plot(app.UIAxes_7,x1,y1,'LineWidth',4,'Color','black')
                            plot(app.UIAxes_7,x3,y3,'LineWidth',4,'Color','b')
                            stem(app.UIAxes_7,x3,z,'LineStyle','none','Color','r')
                            hold(app.UIAxes_7,'off')
                            app.RunningLamp.Color = [0,0,1];
                            pause(1);
                            M = load('TEM_PYTHON\DSOX1204G_TEM_pat3.mat');
                            og= M.y1;
                            M = M.y3';
                            [x,tau] = findpeaks(M,'MinPeakDistance',40,'MinPeakHeight',1);
                            M = tau/length(M);
                            tn = M;
                            b=1.75;
                            d=1.133;
                            kappa=0.014;
                            b=0.78;d=0.99;kappa=0.018;
                            dt = 1/50;
                            yDel = -b*diff(tn) + kappa*d;
                            K =5;
                            T = 1;
                            N =50;
                            K = 4*K+2;
                            w0 = 2*pi/T;
                            F = exp(1j*w0*tn(2:end)'*(-K:K)) - exp(1j*w0*tn(1:end-1)'*(-K:K));
                            F(:,K+1) = tn(2:end) - tn(1:end-1);
                            s = T./(1j*2*pi*(-K:K)); s(K+1) = 1;
                            S = diag(s);

                            ytnHat = pinv(F*S)*yDel';
                            ytnHat = ytnHat'*N;
                            spectrum = transpose(conj(ytnHat(K+1:end)));
                            spectrum = cadzow(spectrum,inf,K);
                            save('TEM_PYTHON/spectrum.mat','spectrum');
                            disp("saved!");

                            xyo = system('python3 TEM_PYTHON/temp3.py');
                            outputsignal = readmatrix('TEM_PYTHON/pulse33_100_hw_recon.csv');
                            hold(app.UIAxes_2,'on')
                            plot(app.UIAxes_2,(outputsignal(:,2)-0.1)/max(outputsignal(:,2)),'--','LineWidth',2,'color',[1,0,0])
                            plot(app.UIAxes_2,(resample(og,200,length(og)))./max(og),'LineWidth',2,'Color',[0,0,0])
                            hold(app.UIAxes_2,'off')
                            %plot((outputsignal(:,2)-0.1)/max(outputsignal(:,2)));hold on;plot((resample(og,200,length(og))-0.2)./max(og));
                            app.RunningLamp.Color = 'r';
                        end
                    end
                end
                if strcmp(app.HardwareSwitch.Value,'On')
                    if app.Person1Button.Value
                        write_serial_TEM(1);
                        DSOX1204G();
                        plot(app.UIAxes,t1,y1);
                        [t,locs] = findpeaks(y2);
                        z = zeros(1,length(y2));
                        z(locs) = y2(locs);
                        hold(app.UIAxes_7,'on')
                        plot(app.UIAxes_7,t1,y2,'color',[0,0.5,0])
                        plot(app.UIAxes_7,t1,y1,'LineWidth',4,'Color','black')
                        plot(app.UIAxes_7,t1,y3,'LineWidth',4,'Color','b')
                        stem(app.UIAxes_7,t1,z,'LineStyle','none','Color','r')
                        hold(app.UIAxes_7,'off')
                        app.RunningLamp.Color = [0,0,1];
                        pause(1);
                        M = load('TEM_PYTHON\DSOX1204G_test.mat');
                        %M = M(:,2)';
                        og = M.y1;
                        M = M.y3';
                        [x,tau] = findpeaks(M,'MinPeakDistance',40,'MinPeakHeight',1);
                        M = tau/length(M);
                        tn = M;
                        b=1.75;
                        d=1.133;
                        kappa=0.014;
                        b=0.78;d=0.99;kappa=0.018;
                        dt = 1/50;
                        yDel = -b*diff(tn) + kappa*d;
                        K =5;
                        T = 1;
                        N =50;
                        K = 4*K+2;
                        w0 = 2*pi/T;
                        F = exp(1j*w0*tn(2:end)'*(-K:K)) - exp(1j*w0*tn(1:end-1)'*(-K:K));
                        F(:,K+1) = tn(2:end) - tn(1:end-1);
                        s = T./(1j*2*pi*(-K:K)); s(K+1) = 1;
                        S = diag(s);
                        ytnHat = pinv(F*S)*yDel';
                        ytnHat = ytnHat'*N;
                        spectrum = transpose(conj(ytnHat(K+1:end)));
                        spectrum = cadzow(spectrum,inf,K);
                        save('TEM_PYTHON\spectrum.mat','spectrum');
                        disp("saved!");
                        xyo = system('python3 TEM_PYTHON\temp1.py');
                        outputsignal = readmatrix('pulse11_100_hw_recon.csv');
                        hold(app.UIAxes_2,'on')
                        plot(app.UIAxes_2,(outputsignal(:,2)-0.1)/max(outputsignal(:,2)),'--','LineWidth',2,'color',[0,1,0])
                        plot(app.UIAxes_2,(resample(og,200,length(og))-0.2)./max(og),'LineWidth',2,'Color',[0,0,0])
                        hold(app.UIAxes_2,'off')
                        %plot((outputsignal(:,2)-0.1)/max(outputsignal(:,2)));hold on;plot((resample(og,200,length(og))-0.2)./max(og));
                        app.RunningLamp.Color = 'r';
                    end
                    if app.Person2Button.Value
                        write_serial_TEM(2);
                        %DSOX1204G();
                        plot(app.UIAxes,t1,y1);
                        [t,locs] = findpeaks(y2);
                        z = zeros(1,length(y2));
                        z(locs) = y2(locs);
                        hold(app.UIAxes_7,'on')
                        plot(app.UIAxes_7,t1,y2,'color',[0,0.5,0])
                        plot(app.UIAxes_7,t1,y1,'LineWidth',4,'Color','black')
                        plot(app.UIAxes_7,t1,y3,'LineWidth',4,'Color','b')
                        stem(app.UIAxes_7,t1,z,'LineStyle','none','Color','r')
                        hold(app.UIAxes_7,'off')
                        app.RunningLamp.Color = [0,0,1];
                        pause(1);
                        M = load('TEM_PYTHON\DSOX1204G_test.mat');
                        %M = M(:,2)';
                        og = M.y1;
                        M = M.y3';
                        [x,tau] = findpeaks(M,'MinPeakDistance',40,'MinPeakHeight',1);
                        M = tau/length(M);
                        tn = M;
                        b=1.75;
                        d=1.133;
                        kappa=0.014;
                        b=0.78;d=0.99;kappa=0.018;
                        dt = 1/50;
                        yDel = -b*diff(tn) + kappa*d;
                        K =7;
                        T = 1;
                        N =50;
                        K = 4*K+2;
                        w0 = 2*pi/T;
                        F = exp(1j*w0*tn(2:end)'*(-K:K)) - exp(1j*w0*tn(1:end-1)'*(-K:K));
                        F(:,K+1) = tn(2:end) - tn(1:end-1);
                        s = T./(1j*2*pi*(-K:K)); s(K+1) = 1;
                        S = diag(s);

                        ytnHat = pinv(F*S)*yDel';
                        ytnHat = ytnHat'*N;
                        spectrum = transpose(conj(ytnHat(K+1:end)));
                        spectrum = cadzow(spectrum,inf,K);
                        save('TEM_PYTHON\spectrum.mat','spectrum');
                        disp("saved!");

                        xyo = system('python3 TEM_PYTHON/temp2.py');
                        outputsignal = readmatrix('TEM_PYTHON/pulse2.csv');
                        outputsignal(1:20,:) = -outputsignal(1:20,:);
                        hold(app.UIAxes_2,'on')
                        plot(app.UIAxes_2,(outputsignal(:,2)-0.1)/max(outputsignal(:,2)),'--','LineWidth',2,'color',[1,0,0])
                        plot(app.UIAxes_2,(resample(og,50,length(og))-0.2)./max(og),'LineWidth',2,'Color',[0,0,0])
                        hold(app.UIAxes_2,'off')
                        %plot((outputsignal(:,2)-0.1)/max(outputsignal(:,2)));hold on;plot((resample(og,200,length(og))-0.2)./max(og));
                        app.RunningLamp.Color = 'r';
                    end
                    if app.Person3Button.Value
                        write_serial_TEM(3);
                        DSOX1204G();
                        plot(app.UIAxes,t1,y1);
                        [t,locs] = findpeaks(y2);
                        z = zeros(1,length(y2));
                        z(locs) = y2(locs);
                        hold(app.UIAxes_7,'on')
                        plot(app.UIAxes_7,t1,y2,'color',[0,0.5,0])
                        plot(app.UIAxes_7,t1,y1,'LineWidth',4,'Color','black')
                        plot(app.UIAxes_7,t1,y3,'LineWidth',4,'Color','b')
                        stem(app.UIAxes_7,t1,z,'LineStyle','none','Color','r')
                        hold(app.UIAxes_7,'off')
                        app.RunningLamp.Color = [0,0,1];
                        pause(1);
                        M = load('TEM_PYTHON\DSOX1204G_test.mat');
                        %M = M(:,2)';
                        og = M.y1;
                        M = M.y3';
                        [x,tau] = findpeaks(M,'MinPeakDistance',40,'MinPeakHeight',1);
                        M = tau/length(M);
                        tn = M;
                        b=1.75;
                        d=1.133;
                        kappa=0.014;
                        b=0.78;d=0.99;kappa=0.018;
                        dt = 1/50;
                        yDel = -b*diff(tn) + kappa*d;
                        K =5;
                        T = 1;
                        N =50;
                        K = 4*K+2;
                        w0 = 2*pi/T;
                        F = exp(1j*w0*tn(2:end)'*(-K:K)) - exp(1j*w0*tn(1:end-1)'*(-K:K));
                        F(:,K+1) = tn(2:end) - tn(1:end-1);
                        s = T./(1j*2*pi*(-K:K)); s(K+1) = 1;
                        S = diag(s);

                        ytnHat = pinv(F*S)*yDel';
                        ytnHat = ytnHat'*N;
                        spectrum = transpose(conj(ytnHat(K+1:end)));
                        spectrum = cadzow(spectrum,inf,K);
                        save('TEM_PYTHON/spectrum.mat','spectrum');
                        disp("saved!");

                        xyo = system('python3 TEM_PYTHON/temp3.py');
                        outputsignal = readmatrix('TEM_PYTHON/pulse33_100_hw_recon.csv');
                        hold(app.UIAxes_2,'on')
                        plot(app.UIAxes_2,(outputsignal(:,2)-0.1)/max(outputsignal(:,2)),'--','LineWidth',2,'color',[1,0,0])
                        plot(app.UIAxes_2,(resample(og,200,length(og)))./max(og),'LineWidth',2,'Color',[0,0,0])
                        hold(app.UIAxes_2,'off')
                        %plot((outputsignal(:,2)-0.1)/max(outputsignal(:,2)));hold on;plot((resample(og,200,length(og))-0.2)./max(og));
                        app.RunningLamp.Color = 'r';
                    end
                end
            end
        end

        % Value changed function: ModeSwitch
        function ModeSwitchValueChanged(app, event)
            cla(app.UIAxes)
            cla(app.UIAxes_2)
            cla(app.UIAxes_4)
            cla(app.UIAxes_7)
            cla(app.UIAxes_5)
            cla(app.UIAxes_6)
            global Val;
            Val = app.ModeSwitch.Value;
            app.RunningLamp.Color = [0,1,0];
        end

        % Selection changed function: SelectpersonButtonGroup
        function SelectpersonButtonGroupSelectionChanged(app, event)

        end

        % Cell edit callback: UITable
        function UITableCellEdit(app, event)
            indices = event.Indices;
            newData = event.NewData;

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'ECG Signal')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes_2 = uiaxes(app.UIFigure);
            title(app.UIAxes_2, 'ECG reconstruction')
            xlabel(app.UIAxes_2, 'X')
            ylabel(app.UIAxes_2, 'Y')
            zlabel(app.UIAxes_2, 'Z')
            app.UIAxes_4 = uiaxes(app.UIFigure);
            title(app.UIAxes_4, 'HRM VPW-FRI')
            xlabel(app.UIAxes_4, 'X')
            ylabel(app.UIAxes_4, 'Y')
            zlabel(app.UIAxes_4, 'Z')
            app.UIAxes_5 = uiaxes(app.UIFigure);
            title(app.UIAxes_5, 'HRM DIFF. VPW-FRI')
            xlabel(app.UIAxes_5, 'X')
            ylabel(app.UIAxes_5, 'Y')
            zlabel(app.UIAxes_5, 'Z')
            app.UIAxes_6 = uiaxes(app.UIFigure);
            title(app.UIAxes_6, 'HRM ECG-TEM')
            xlabel(app.UIAxes_6, 'X')
            ylabel(app.UIAxes_6, 'Y')
            zlabel(app.UIAxes_6, 'Z')
            app.UIAxes_7 = uiaxes(app.UIFigure);
            title(app.UIAxes_7, 'Integration and time instances')
            xlabel(app.UIAxes_7, 'X')
            ylabel(app.UIAxes_7, 'Y')
            zlabel(app.UIAxes_7, 'Z')
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageSource = 'Weizmann_gray.png';
            app.Image_2 = uiimage(app.UIFigure);
            app.Image_2.ImageSource = 'SAMPLlab_logo1.png';
            app.HeartRateMonitoringforECGsignalswithTimeEncodingMachineLabel = uilabel(app.UIFigure);
            app.HeartRateMonitoringforECGsignalswithTimeEncodingMachineLabel.Text = {'Heart Rate Monitoring for ECG signals with'; '            Time Encoding Machine'};
            app.SCENARIOSETTINGSPanel = uipanel(app.UIFigure);
            app.SCENARIOSETTINGSPanel.Title = 'SCENARIO SETTINGS';
            app.ModeSwitchLabel = uilabel(app.SCENARIOSETTINGSPanel);
            app.ModeSwitchLabel.Text = 'Mode';
            app.ModeSwitch = uiswitch(app.SCENARIOSETTINGSPanel, 'slider');
            app.ModeSwitch.Items = {'Simulation', 'Hybrid'};
            app.ModeSwitch.ValueChangedFcn = createCallbackFcn(app, @ModeSwitchValueChanged, true);
            app.ModeSwitch.Value = 'Simulation';
            app.HardwareSwitchLabel = uilabel(app.SCENARIOSETTINGSPanel);
            app.HardwareSwitchLabel.Text = 'Hardware';
            app.HardwareSwitch = uiswitch(app.SCENARIOSETTINGSPanel, 'slider');
            app.HardwareSwitch.Value = 'On';
            app.StartButton = uibutton(app.SCENARIOSETTINGSPanel, 'push');
            app.StartButton.ButtonPushedFcn = createCallbackFcn(app, @StartButtonPushed, true);
            app.StartButton.Text = 'Start';
            app.SelectpersonButtonGroup = uibuttongroup(app.SCENARIOSETTINGSPanel);
            app.SelectpersonButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @SelectpersonButtonGroupSelectionChanged, true);
            app.SelectpersonButtonGroup.Title = 'Select person';
            app.Person1Button = uiradiobutton(app.SelectpersonButtonGroup);
            app.Person1Button.Text = 'Person 1';
            app.Person1Button.Value = true;
            app.Person2Button = uiradiobutton(app.SelectpersonButtonGroup);
            app.Person2Button.Text = {'Person 2'; ''};
            app.Person3Button = uiradiobutton(app.SelectpersonButtonGroup);
            app.Person3Button.Text = 'Person 3';
            app.Image_3 = uiimage(app.UIFigure);
            app.Image_3.Visible = 'off';
            app.Image_3.ImageSource = 'first_table.png';
            app.SamplingRatesKHzPanel = uipanel(app.UIFigure);
            app.SamplingRatesKHzPanel.Title = 'Sampling Rates [ KHz]';
            app.NyquistEditFieldLabel = uilabel(app.SamplingRatesKHzPanel);
            app.NyquistEditFieldLabel.Text = 'Nyquist ';
            app.NyquistEditField = uieditfield(app.SamplingRatesKHzPanel, 'numeric');
            app.ProposredApproachLabel = uilabel(app.SamplingRatesKHzPanel);
            app.ProposredApproachLabel.Text = {'Proposred '; 'Approach'};
            app.ProposredApproachEditField = uieditfield(app.SamplingRatesKHzPanel, 'numeric');
            app.Image_4 = uiimage(app.UIFigure);
            app.Image_4.ImageSource = 'IF_TEMv7.png';
            app.RunningLampLabel = uilabel(app.UIFigure);
            app.RunningLampLabel.Text = 'Running';
            app.RunningLamp = uilamp(app.UIFigure);
            app.UITable = uitable(app.UIFigure);
            app.UITable.ColumnName = {'Method'; 'Success rate'; 'PCC'; 'MAE'; 'RMSE'};
            app.UITable.RowName = {};
            app.UITable.CellEditCallback = createCallbackFcn(app, @UITableCellEdit, true);
            app.ContextMenu = uicontextmenu(app.UIFigure);
            app.Menu = uimenu(app.ContextMenu);
            app.Menu.Text = 'Menu';
            app.Menu2 = uimenu(app.ContextMenu);
            app.Menu2.Text = 'Menu2';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ECG_TEM_GUI

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end